classdef chatrobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ChatRobotMenu        matlab.ui.container.Menu
        ChatRobot_AdvMenu    matlab.ui.container.Menu
        InputTextArea        matlab.ui.control.TextArea
        InputTextAreaLabel   matlab.ui.control.Label
        DropDown_2           matlab.ui.control.DropDown
        DropDown             matlab.ui.control.DropDown
        RecordingButton      matlab.ui.control.Button
        TextInputButton      matlab.ui.control.Button
        OutputTextArea       matlab.ui.control.TextArea
        OutputTextAreaLabel  matlab.ui.control.Label
        SpeechFileButton     matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mdl 
        audio_y
        audio_fs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mdl = gpt2;
            speechClient("wav2vec2.0")
            imshow('RabRotbot.jfif','Parent',app.UIAxes);

        end

        % Button pushed function: SpeechFileButton
        function SpeechFileButtonPushed(app, event)

            % load Audio
            audioIndex = app.DropDown.Value;
            disp(audioIndex)

            if (audioIndex == "speech 1")
                [app.audio_y,app.audio_fs] = audioread("recorded_audio01.wav");
            
            elseif(audioIndex == "speech 2")
                [app.audio_y,app.audio_fs] = audioread("recorded_audio01.wav");
            
            elseif(audioIndex == "speech 3")
                [app.audio_y,app.audio_fs] = audioread("recorded_audio01.wav");
            end
            sound(app.audio_y,app.audio_fs)

            % Audio to Text
            transcript = speech2text(app.audio_y,app.audio_fs);
            app.InputTextArea.Value = transcript; % show text data

            % Text to GPT
            summary = generateSummary(app.mdl,transcript) % show text data
            %pause(3);
             
            % Text to Speech
            [speech,fs] = text2speech(summary);
            app.OutputTextArea.Value = summary;
            sound(speech,fs)
        end

        % Button pushed function: TextInputButton
        function TextInputButtonPushed(app, event)
            % load Text
            textIndex = app.DropDown_2.Value;
            disp(textIndex)

            if (textIndex == "Text 1")
                textData = "Will AI dominate the world";
            
            elseif(textIndex == "Text 2")
                textData = "Do you believe in aliens";
            
            elseif(textIndex == "Text 3")
                textData = "What do you think of MATLAB";
            end

            app.InputTextArea.Value = textData; % show text data
            [speech,fs] = text2speech(textData);
            sound(speech,fs)
            %---------------------------------------------
            % Text to GPT
            summary = generateSummary(app.mdl,textData) % show text data
            app.OutputTextArea.Value = summary;
            
            %pause(3);
            
            % Text to Speech
            [speech,fs] = text2speech(summary);
            sound(speech,fs)

        end

        % Button pushed function: RecordingButton
        function RecordingButtonPushed(app, event)
            % record setting
            Fs = 44100;
            BitsPerSample = 16; 
            NumChannels = 1; 
            
            % starting recording-------------------------------
            recObj = audiorecorder(Fs, BitsPerSample, NumChannels);
                disp('開始錄音...');
            record(recObj);
            
            pause(6);
            
            stop(recObj);
                disp('結束錄音...');
            
            y = getaudiodata(recObj); %get audio data
            play(recObj); % play one time 
            % sound(y,Fs)
            
            % Speech to Text------------------------------------
            transcript = speech2text(y,Fs)
            app.InputTextArea.Value = transcript; % show text data
            
            % Text to GPT
            summary = generateSummary(app.mdl,transcript) % show text data
            app.OutputTextArea.Value = summary;
            
            % Text to GPT
            [speech,fs] = text2speech(summary);
            sound(speech,fs)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChatRobotMenu = uimenu(app.UIFigure);
            app.ChatRobotMenu.Text = 'ChatRobot';
            app.ChatRobot_AdvMenu = uimenu(app.UIFigure);
            app.ChatRobot_AdvMenu.Text = 'ChatRobot_Adv';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.SpeechFileButton = uibutton(app.UIFigure, 'push');
            app.SpeechFileButton.ButtonPushedFcn = createCallbackFcn(app, @SpeechFileButtonPushed, true);
            app.SpeechFileButton.Text = 'Speech File';
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.TextInputButton = uibutton(app.UIFigure, 'push');
            app.TextInputButton.ButtonPushedFcn = createCallbackFcn(app, @TextInputButtonPushed, true);
            app.TextInputButton.Text = 'Text Input';
            app.RecordingButton = uibutton(app.UIFigure, 'push');
            app.RecordingButton.ButtonPushedFcn = createCallbackFcn(app, @RecordingButtonPushed, true);
            app.RecordingButton.Text = 'Recording ';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'speech 1', 'speech 2', 'speech 3'};
            app.DropDown.Value = 'speech 1';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'Text 1', 'Text 2', 'Text 3'};
            app.DropDown_2.Value = 'Text 1';
            app.InputTextAreaLabel = uilabel(app.UIFigure);
            app.InputTextAreaLabel.Text = 'Input';
            app.InputTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chatrobot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end